classdef sysIDFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PreviewButton                 matlab.ui.control.Button
        MinimumstepintervalDropDown   matlab.ui.control.DropDown
        MinimumstepintervalDropDownLabel  matlab.ui.control.Label
        ConnectButton                 matlab.ui.control.Button
        FlyingaxisLabel               matlab.ui.control.Label
        LeftRightButton               matlab.ui.control.StateButton
        InputamplitudeEditField       matlab.ui.control.NumericEditField
        InputamplitudeEditFieldLabel  matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        ParametersettingLabel         matlab.ui.control.Label
        RuntimeEditField              matlab.ui.control.NumericEditField
        RuntimeEditFieldLabel         matlab.ui.control.Label
        SystemIDDataGeneratorLabel    matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% setting parameter initialization 
        time=0; % time specify the total operation time 
        min_time=0.25; % min_time specify the min_time interval hold in PBRS
        output=0;  % output specify the amplitude of the input signal 
        direction=1; % 1 represent the left/right direction  , 2 represent the up/down direction 
        %% drone initialization 
        droneobj=0;
        cameraobj=0;
        pixel=[0 0 0 ];
        error=0;
        distance=450;
    end
    
    methods (Access = private)
        
        function pixel = calibirate(app)
            % this fuction is aim to figure out the initial pixel of the
            % drone on the image 
            frame=snapshot(app.cameraobj);   % getting a frame 
            pixel=[0 0 0];
            frame1=frame(:,1:600,:);  % cutting the unwanted part of image (not neccessary if the backgound is all white )
            % filter the drone out by holding grayscale threshold 
            gray1=rgb2gray(frame1);
            grayFilter=gray1<50;    
            % calculate the center of mass on the image , which is seem as
            % the original position of the drone 
            [x, y] = meshgrid(1:size(grayFilter, 2), 1:size(grayFilter, 1));
            weightedx = x .* grayFilter;
            weightedy = y .* grayFilter;
            xcentre = sum(weightedx(:)) / sum(grayFilter(:));
            ycentre = sum(weightedy(:)) / sum(grayFilter(:));
            % assign the value to the output 
            pixel(1)=xcentre;
            pixel(2)=ycentre;
        end


        function pos = getPosition(app)
            focal=820;   % focal is found by doing camera calibiration 
            pos=[0 0 0];
            frame=snapshot(app.cameraobj);
                
            if app.direction==1 % left / right direction 
                frame1=frame(:,41:600,:);
            else  % up/down dircetion 
                frame1=frame(:,floor(640/3):floor(640*2/3),:); % when doing up/down direction , image can be reduced
            end 
            % filter the drone out by holding grayscale threshold 
            gray1=rgb2gray(frame1);
            grayFilter=gray1<50;
            % finding the center of mass in the image 
            [x, y] = meshgrid(1:size(grayFilter, 2), 1:size(grayFilter, 1));
            weightedx = x .* grayFilter;
            weightedy = y .* grayFilter;
            xcentre = sum(weightedx(:)) / sum(grayFilter(:));
            ycentre = sum(weightedy(:)) / sum(grayFilter(:));
            
            % calculate the difference of pixel and transforming into pixel of
            % distance by considering focal
            imgpos = [xcentre-app.pixel(1),app.pixel(2)-ycentre];
            pos(1)= imgpos(1)*app.distance/focal;   % position x 
            pos(2)= imgpos(2)*app.distance/focal;   % position y
            disp(pos(2))
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RuntimeEditField
        function RuntimeEditFieldValueChanged(app, event)
            app.time = app.RuntimeEditField.Value;
            
        end

        % Value changed function: MinimumstepintervalDropDown
        function MinimumstepintervalDropDownValueChanged(app, event)
             app.min_time = app.MinimumstepintervalDropDown.Value;
              
        end

        % Value changed function: InputamplitudeEditField
        function InputamplitudeEditFieldValueChanged(app, event)
            app.output = app.InputamplitudeEditField.Value;
            
        end

        % Value changed function: LeftRightButton
        function LeftRightButtonValueChanged(app, event)
     
            if app.direction==1 
                app.LeftRightButton.Text="Up/Down";
                app.direction=2;
            else 
                app.LeftRightButton.Text="Left/Right";
                app.direction=1;
            end 
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
    
             app.time=0;
             app.output=0;
             app.RuntimeEditField.Value=0;
             app.InputamplitudeEditField.Value=0;
             app.direction=1;
             app.LeftRightButton.Text="Left/Right";
             evalin("base",'clear drone');
             cla(app.UIAxes) ;
             
             
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                disp("the program is running ")
                app.RunButton.Text="Running";
                % ==== param setting ======================================================
                dt=1/20; % sampling time 
                %% ==== setting the figure ====================================
                app.UIAxes.XLimMode="manual";
                app.UIAxes.XAxis.Limits=[0 app.time];
                %% ==== generating input signal ===============================
                vec=rand(1,app.time/app.min_time);
                raw=(vec>0.5)*1-(vec<0.5)*1;
                target=reshape(repmat(raw,uint8(app.min_time/dt),1),[1,length(raw)*app.min_time/dt]);
                input=(target.')*app.output;
    
                %% ==== takeoff ===============================================
                % the flying command help the drone to locate on around 100cm
                % above the ground after taking off 
                takeoff(app.droneobj);
                pause(1)
                position=zeros(app.time/dt,3); % [x y z] 
                rc(app.droneobj,0,0,-50,0);
                pause(5)
                rc(app.droneobj,0,0,0,0);
                pause(1)
                moveup(app.droneobj,"Distance",0.8);
                rc(app.droneobj,0,0,0,0);
                pause(3)
               
                %% ==== main loop ==========================================================
                disp("main loop start");
               
                i=0;
                while i<app.time/dt
                    i=i+1;
                    timer=tic;
                    position(i,:)=app.getPosition();
                    if app.direction==1
                        rc(app.droneobj,app.output*target(i),0,0,0);
                        if abs(position(i,1))>150
                            rc(app.droneobj,0,0,0,0);
                            land(app.droneobj);
                            msgbox("flying out of safe region","Error",'error')
                            break
                        end 
                    else
                        rc(app.droneobj,0,0,app.output*target(i),0);
                        if position(i,2)>200
                            rc(app.droneobj,0,0,0,0);
                            land(app.droneobj);
                            msgbox("flying out of safe region","Error",'error')
                            break
                        end 
                    end 
                    plot(app.UIAxes,dt:dt:i*dt,position(1:i,app.direction));
                    
                    t=toc(timer);
            
                    pause(dt-t);
                end
                disp("main loop finish")
                rc(app.droneobj,0,0,0,0);
                pause(3)
                land(app.droneobj);
            catch ME
                land(app.droneobj);
                msgbox("Please check the parameter setting ","Error",'error')
            end 
            %% ==== data processing  ===================================================
            % if the drone is flying in left/right direction, using a
            % lowpass filter on the data 
            if app.direction==1
                data=position(:,app.direction);
                data=lowpass(data,4,20); 
            
            else
                data=position(:,app.direction);
            end 
            app.RunButton.Text="Run";

            assignin("base","data_position",data);
            assignin("base","input",input);
            disp("the program is done ")
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % clear the drone obj in the workspace to avoid double 
            % connection error 
            app.ConnectButton.Text="Connecting...";
            evalin("base","clear drone");
            pause(2)
            app.droneobj=0;
            try 
                %% ==== drone initialization ==============================
                app.droneobj=ryze("TelloEDU");
                app.cameraobj=webcam("USB Video Device");
                app.pixel=app.calibirate();
                assignin("base","drone",app.droneobj);
                %% ==== success and error handling ======================== 
                disp("the drone is connected !")
                app.ConnectButton.Text="Connected!";
            catch ME
                % if an connection error is catch , the msgbox will pop out
                if strcmp(ME.identifier,'MATLAB:ryze:general:connectionFailed')
                    msgbox("Please check the wifi connection with Tello ","Error",'error')
                    app.ConnectButton.Text="Connect";
                end 
            end 
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            try 
                if app.direction==1
                    frame=snapshot(app.cameraobj);
                    frame1=frame(:,41:600,:);
                    gray1=rgb2gray(frame1);
                    grayFilter=gray1<50;
                    figure;
                    imshow(frame1)
                    figure;
                    imshow(grayFilter);
                else 
                    frame=snapshot(app.cameraobj);
          
                    frame1=frame(:,floor(640/3):floor(2*640/3),:);
                    gray1=rgb2gray(frame1);
                    grayFilter=gray1<50;
                    figure;
                    imshow(frame)
                    figure;
                    imshow(grayFilter);
                end 
            catch ME
                if strcmp(ME.identifier,"MATLAB:UndefinedFunction")
                    msgbox("Please execute connect first ","Error",'error')
                end 
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'altitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.SystemIDDataGeneratorLabel = uilabel(app.UIFigure);
            app.SystemIDDataGeneratorLabel.Text = 'System ID Data Generator ';
            app.RuntimeEditFieldLabel = uilabel(app.UIFigure);
            app.RuntimeEditFieldLabel.Text = 'Run time ';
            app.RuntimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RuntimeEditField.ValueChangedFcn = createCallbackFcn(app, @RuntimeEditFieldValueChanged, true);
            app.ParametersettingLabel = uilabel(app.UIFigure);
            app.ParametersettingLabel.Text = 'Parameter setting';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InputamplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.InputamplitudeEditFieldLabel.Text = 'Input amplitude';
            app.InputamplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputamplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @InputamplitudeEditFieldValueChanged, true);
            app.LeftRightButton = uibutton(app.UIFigure, 'state');
            app.LeftRightButton.ValueChangedFcn = createCallbackFcn(app, @LeftRightButtonValueChanged, true);
            app.LeftRightButton.Text = {'Left/Right'; ''};
            app.FlyingaxisLabel = uilabel(app.UIFigure);
            app.FlyingaxisLabel.Text = 'Flying axis = ';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.MinimumstepintervalDropDownLabel = uilabel(app.UIFigure);
            app.MinimumstepintervalDropDownLabel.Text = 'Minimum step interval';
            app.MinimumstepintervalDropDown = uidropdown(app.UIFigure);
            app.MinimumstepintervalDropDown.Items = {'0.05', '0.1', '0.2', '0.25', '0.5'};
            app.MinimumstepintervalDropDown.ValueChangedFcn = createCallbackFcn(app, @MinimumstepintervalDropDownValueChanged, true);
            app.MinimumstepintervalDropDown.Value = '0.25';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sysIDFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end